classdef Air_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AirInterface5GLabel             matlab.ui.control.Label
        OFDMPanel                       matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        InsertDCNullCheckBox            matlab.ui.control.CheckBox
        WindowingCheckBox               matlab.ui.control.CheckBox
        PioltInputCheckBox              matlab.ui.control.CheckBox
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        CyclicPrefixLengthEditFieldLabel  matlab.ui.control.Label
        CyclicPrefixLengthEditField     matlab.ui.control.NumericEditField
        OFDMSymbolsEditFieldLabel       matlab.ui.control.Label
        OFDMSymbolsEditField            matlab.ui.control.NumericEditField
        TransmitAntennasEditFieldLabel  matlab.ui.control.Label
        TransmitAntennasEditField       matlab.ui.control.NumericEditField
        SubcarrierSpacingHzEditFieldLabel  matlab.ui.control.Label
        SubcarrierSpacingHzEditField    matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Pax 
    end
    
    methods (Access = public)
        
        function test(app)
           hMod = comm.OFDMModulator;
           hMod.FFTLength = 128;
           hMod.NumSymbols = 2;
           
           disp(hMod)
           
           showResourceMapping(hMod)
        end
        
        function test2(app)
            %sine = dsp.SineWave(amp,freq,phase,Name,Value)
            sine1 = dsp.SineWave();
            sine1.Amplitude = app.EditAmplitude.Value;
            sine1.Frequency = app.EditFrequency.Value;
            sine1.PhaseOffset = app.EditPhaseOffset.Value * pi/2;
            sine1.SamplesPerFrame = app.EditSamplesPerFrame.Value;
            y = sine1();
            
            plot(app.UIAxes , y );
            
        end
        
        function test3(app)

            hMod = comm.OFDMModulator;

            hMod.FFTLength = 128;
            hMod.NumSymbols = 2;

            hMod.PilotInputPort = true;

            hMod.PilotCarrierIndices = [12; 56; 89; 100];

            hMod.NumTransmitAntennas = 1 ;

            disp(hMod);
            showResourceMapping(hMod);

            %gx = uiaxes("Parent", app.OFDMPanel);

        %    plot(app.OFDMPanel , gx);
        %    p = uipanel;
        %    p = uipanel(app.OFDMPanel);

        %    app.OFDMPanel(geoaxes);

        end
        
        function test4(app)
            
        %% Generate OFDM Waveform
        % OFDM configuration:
        ofdmMod = comm.OFDMModulator('FFTLength', app.FFTLengthEditField.Value, ...
        'NumGuardBandCarriers', [6;5],...
        'InsertDCNull', app.InsertDCNullCheckBox.Value,...
        'CyclicPrefixLength', app.CyclicPrefixLengthEditField.Value, ...
        'Windowing', app.WindowingCheckBox.Value, ...
        'NumSymbols', app.OFDMSymbolsEditField.Value, ...
        'NumTransmitAntennas', app.TransmitAntennasEditField.Value, ...
        'PilotInputPort', app.PioltInputCheckBox.Value);
        
        M = 4; 	 % Modulation order
        % input bit source:
        in = randi([0 1], 10600, 1);
        
        dataInput = qammod(in, M, 'gray', 'InputType', 'bit', 'UnitAveragePower', true);
        ofdmInfo = info(ofdmMod);
        ofdmSize = ofdmInfo.DataInputSize;
        dataInput = reshape(dataInput, ofdmSize);
        
        % waveform generation:
        waveform = ofdmMod(dataInput); % y axis, x axis = time
        
        %covert time signal to frequency signal then plot to axis
        
        
        Fs = 6.4 * app.SubcarrierSpacingHzEditField.Value; % sample rate of waveform
        
        %% Visualize OFDM Waveform
        % Spectrum Analyzer
        spectrum = dsp.SpectrumAnalyzer('SampleRate', Fs);
        spectrum(waveform);
        release(spectrum);
        
        % OFDM Subcarrier Mapping
        showResourceMapping(ofdmMod);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.test4()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AirInterface5GLabel = uilabel(app.UIFigure);
            app.AirInterface5GLabel.Tooltip = {'Youssef Alchaer'; 'Anotheny Vellone'};
            app.AirInterface5GLabel.Text = 'Air Interface 5G';
            app.OFDMPanel = uipanel(app.UIFigure);
            app.OFDMPanel.Title = 'OFDM';
            app.GenerateButton = uibutton(app.OFDMPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.InsertDCNullCheckBox = uicheckbox(app.OFDMPanel);
            app.InsertDCNullCheckBox.Text = 'Insert DC Null';
            app.WindowingCheckBox = uicheckbox(app.OFDMPanel);
            app.WindowingCheckBox.Text = 'Windowing';
            app.PioltInputCheckBox = uicheckbox(app.OFDMPanel);
            app.PioltInputCheckBox.Text = 'Piolt Input';
            app.FFTLengthEditFieldLabel = uilabel(app.OFDMPanel);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FFTLengthEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.FFTLengthEditField.Value = 64;
            app.CyclicPrefixLengthEditFieldLabel = uilabel(app.OFDMPanel);
            app.CyclicPrefixLengthEditFieldLabel.Text = 'Cyclic Prefix Length';
            app.CyclicPrefixLengthEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.CyclicPrefixLengthEditField.Value = 16;
            app.OFDMSymbolsEditFieldLabel = uilabel(app.OFDMPanel);
            app.OFDMSymbolsEditFieldLabel.Text = 'OFDM Symbols';
            app.OFDMSymbolsEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.OFDMSymbolsEditField.Value = 100;
            app.TransmitAntennasEditFieldLabel = uilabel(app.OFDMPanel);
            app.TransmitAntennasEditFieldLabel.Text = 'Transmit Antennas';
            app.TransmitAntennasEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.TransmitAntennasEditField.Value = 1;
            app.SubcarrierSpacingHzEditFieldLabel = uilabel(app.OFDMPanel);
            app.SubcarrierSpacingHzEditFieldLabel.Text = 'Subcarrier Spacing (Hz)';
            app.SubcarrierSpacingHzEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.SubcarrierSpacingHzEditField.Value = 100000;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end